classdef Modalanalyse_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        InfosZurNutzungPanel            matlab.ui.container.Panel
        TextArea_5                      matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ImportTab_2                     matlab.ui.container.Tab
        SensorDataConnectionLamp        matlab.ui.control.Lamp
        SensorDataConnectionEditField   matlab.ui.control.EditField
        SensorDataConnectionButton      matlab.ui.control.Button
        InfoboxTextArea_2               matlab.ui.control.TextArea
        InfoboxTextArea_2Label          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SensorDataConnectionLabel       matlab.ui.control.Label
        SensorDataConnectionTable       matlab.ui.control.Table
        DataSensorTable                 matlab.ui.control.Table
        SensorConnectionLabel           matlab.ui.control.Label
        SensorPositionLabel             matlab.ui.control.Label
        DataSensorsLabel                matlab.ui.control.Label
        SensorConnectionTable           matlab.ui.control.Table
        SensorPositionTable             matlab.ui.control.Table
        PositionAxes_2                  matlab.ui.control.UIAxes
        ConnectionLamp                  matlab.ui.control.Lamp
        ConnectionEditField             matlab.ui.control.EditField
        ConnectionButton                matlab.ui.control.Button
        PositionLamp                    matlab.ui.control.Lamp
        PositionEditField               matlab.ui.control.EditField
        PositionButton                  matlab.ui.control.Button
        LoadLamp                        matlab.ui.control.Lamp
        LoadEditField                   matlab.ui.control.EditField
        LoadDataButton                  matlab.ui.control.Button
        VisualisierungSignaleTab        matlab.ui.container.Tab
        SliderSignale                   matlab.ui.control.RangeSlider
        SliderFrequenzspektrum          matlab.ui.control.RangeSlider
        SensorenListBox                 matlab.ui.control.ListBox
        SensorenListBoxLabel            matlab.ui.control.Label
        Signal_zAchse                   matlab.ui.control.UIAxes
        Signal_xAchse                   matlab.ui.control.UIAxes
        Signal_yAchse                   matlab.ui.control.UIAxes
        AxesFrequenzspektrum            matlab.ui.control.UIAxes
        PeakAuswahlTab                  matlab.ui.container.Tab
        CalculateFDDPanel               matlab.ui.container.Panel
        WelchsAerageSwitch              matlab.ui.control.Switch
        WelchsAerageSwitchLabel         matlab.ui.control.Label
        NWindowEditField                matlab.ui.control.NumericEditField
        NWindowEditFieldLabel           matlab.ui.control.Label
        MACEditField                    matlab.ui.control.NumericEditField
        MACEditFieldLabel               matlab.ui.control.Label
        EnhancedButton                  matlab.ui.control.Button
        FinishSelectionButton           matlab.ui.control.Button
        SelectPeaksButton               matlab.ui.control.Button
        CalculateCPSDButton             matlab.ui.control.Button
        SliderFDD                       matlab.ui.control.RangeSlider
        Panel_2                         matlab.ui.container.Panel
        FrequenzenHzLabel               matlab.ui.control.Label
        FrequenzenTable                 matlab.ui.control.Table
        BerechneteModenLabel            matlab.ui.control.Label
        ModesTable                      matlab.ui.control.Table
        DeselectButton                  matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        UIAxesFDD                       matlab.ui.control.UIAxes
        EFDDDetailsTab                  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        VerbesserteModenundrekonstruiertesSignalTab  matlab.ui.container.Tab
        SignalSlider                    matlab.ui.control.RangeSlider
        ModeGroupSlider                 matlab.ui.control.RangeSlider
        MACGroupsSlider                 matlab.ui.control.RangeSlider
        UIAxesFrequenzenMitMAC          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        AuswahlderdarzustellendenFrequenzListBox  matlab.ui.control.ListBox
        AngezeigteFrequenzLabel         matlab.ui.control.Label
        FrequenzenundDmpfungsgradejeSDOFSystemLabel  matlab.ui.control.Label
        FrequenzenTable_3               matlab.ui.control.Table
        DmpfungsBerechnungTab           matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        AngezeigteFrequenzListBox_2     matlab.ui.control.ListBox
        AngezeigteFrequenzListBox_2Label  matlab.ui.control.Label
        SpeichereDmpfungsgradButton     matlab.ui.control.Button
        GemittelteDmpfungEditField      matlab.ui.control.NumericEditField
        GemittelteDmpfungEditFieldLabel  matlab.ui.control.Label
        MitRegressionermittelteDmpfungEditField  matlab.ui.control.NumericEditField
        MitRegressionermittelteDmpfungEditFieldLabel  matlab.ui.control.Label
        UITableDaempfung                matlab.ui.control.Table
        Slider                          matlab.ui.control.RangeSlider
        UIAxesHistogramDurchschnitt     matlab.ui.control.UIAxes
        UIAxesLogPeaks                  matlab.ui.control.UIAxes
        UIAxesSignalMitPeaks            matlab.ui.control.UIAxes
        EigenformenTab                  matlab.ui.container.Tab
        ModeneFDDLabel                  matlab.ui.control.Label
        ModenFDDLabel                   matlab.ui.control.Label
        VerbesserteModesTable_2         matlab.ui.control.Table
        AnnimationderVerschiebungLabel  matlab.ui.control.Label
        DiagolalButton                  matlab.ui.control.Button
        SeitansichtXZEbeneButton        matlab.ui.control.Button
        SeitansichtYZEbeneButton        matlab.ui.control.Button
        DraufsichtXYEbeneButton         matlab.ui.control.Button
        ControlleAnimationPanel         matlab.ui.container.Panel
        VerbessertLamp                  matlab.ui.control.Lamp
        UrspruenglichLamp               matlab.ui.control.Lamp
        DifferenzderModenButtonGroup    matlab.ui.container.ButtonGroup
        inaktivButton                   matlab.ui.control.RadioButton
        aktivButton                     matlab.ui.control.RadioButton
        TextArea_4                      matlab.ui.control.TextArea
        VerbesserteFrequenzenListBox    matlab.ui.control.ListBox
        WhleeineFrequenzLabel_2         matlab.ui.control.Label
        SkallierenderVerschiebungEditField  matlab.ui.control.NumericEditField
        SkallierenderVerschiebungEditFieldLabel  matlab.ui.control.Label
        UrspruenglicheFrequenzenListBox  matlab.ui.control.ListBox
        WhleeineFrequenzLabel           matlab.ui.control.Label
        PauseButton                     matlab.ui.control.Button
        StartAnimationButton            matlab.ui.control.Button
        AnimationTable                  matlab.ui.control.Table
        MovementAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        filePathSchwingungen = "";  % Pfad zur Datei mit Schwingungsdaten
        filePathPositionSensoren = "";  % Pfad zur Datei mit Sensorpositionen
        filePathVerbindungSensoren = "";  % Pfad zur Datei mit Sensorverbindungen
        filePathVerbindungDatenSensoren = ""; % Pfad zur Datei mit Verbindung zwischen Daten und Sensoren
        
        % Daten der Hochgeladenen Files
        timeVector;                 % Vector mit Zeitpunkten der Messdaten 
        dataSensors;                % Daten der Sensoren  
        positionSensors;            % Positionen der Sensoren
        connectionSensors;          % Verbindungen der Sensoren
        sensorToNodeMap;            % Verbindung der Daten zu den Sensoren als Map
        
        % Allgemeine infos
        anzahlSensoren;
        anzahlDatenpunkte;
        dauerMessung;
        abtastRate;

        % Ergebnisse FDD / EFDD
        singularValues;             % Vector der Amplituden (Eigenwerte)
        frequencies;                % Vector der Frequenzen 
        selectedFrequencies;        % Matrix Ausgewählte Frequenzen [N x 2]
        eFrequencies;               % verfeinerte Eigenfrequenzen
        modes;                      % Modenformen aus SVD
        selectedModes;              % Ermittelte Eigenvektoren
        eModes;                     % Verbesserte Moden
        daempfungsgrad;             % Dämpfungsgrad der Ausgewählten Frequenzen 
        frequenzGruppen;            % Frequenzguppen für nach MAC-Kriterium zusammenhängende Moden

        % Rekonstruiertes Signal
        psd_auswahl;                % Ausgewählte PSD
        t_rec;                      % Zeitvektor des rekonstruierten Signals
        signal_rec;                 % rekonstriertes Signal

        % Button Commands / Controlls
        peakSelection = 0;          % Info zum Status des Ausgewählten Peaks
        animationStatus = false;    % Info zum Status der Animation
        selectedItemFreq = 1;       % Info zum ausgewählrem Sensor (erstes Element als default)
        scale = 1;                  % Skalierungsfaktor für die Animation
        perspective = [45,45];      % Speicher den Ausgewählten view für die Videoannimation
        mousePos;                   % Koordinaten der Mausposition bei klick
        selectionProcess = false;   % Boolean für Auswahlprozess
        selectedAnimation = 1;      % Variable, die Angibt, ob ursprüngliche oder Verbesserte Frequenz dargestellt werden soll. 

        nodeToSensorsMap;
    end

    properties (Constant)

        % Map, die für alle Instanzen der App gleich ist. Enthält zuweisung
        % der Richtungen x,y,z zu Zahlenwerten.
        mapZuweisungRichtung = containers.Map({'x', 'y', 'z'}, [1, 2, 3]); 

    end

    methods

	    function setPeakSelection(app, value)
            % Setze den Wert von peakSelection
            app.peakSelection = value;
        end
    
        function value = getPeakSelection(app)
            % Hole den Wert von peakSelection
            value = app.peakSelection;
        end

        function setAnimationStatus(app, value)
            % Setze den Wert von peakSelection
            app.animationStatus = value;
        end
    
        function value = getAnimationStatus(app)
            % Hole den Wert von peakSelection
            value = app.animationStatus;
        end

        function value = getSensorToNodeMap(app)
            % Hole den Wert von peakSelection
            value = app.sensorToNodeMap;
        end

        function value = getPerspective(app)
            % Hole den Wert für die Ansicht
            value = app.perspective;
        end

        function setPerspective(app, perspective)

            app.perspective = perspective;
        end

        function nodeToSensorsMap = createNodeToSensorsMap(app)
            % Create a reverse map from node numbers to sensor channels and directions
            nodeToSensorsMap = containers.Map('KeyType', 'double', 'ValueType', 'any');
            
            map = app.getSensorToNodeMap;
            sensorChannels = map.keys();
            
            for i = 1:length(sensorChannels)
                channel = sensorChannels{i};
                nodeInfo = app.sensorToNodeMap(channel);
                node = nodeInfo.Knoten;
                direction = nodeInfo.Richtung;
                
                % If node doesn't exist in map, create an empty structure
                if ~isKey(nodeToSensorsMap, node)
                    nodeToSensorsMap(node) = struct('x', [], 'y', [], 'z', []);
                end
                
                % Get the current struct, modify it, and put it back
                currentStruct = nodeToSensorsMap(node);
                currentStruct.(direction) = channel;
                nodeToSensorsMap(node) = currentStruct;
            end

            app.nodeToSensorsMap = nodeToSensorsMap;
            
            app.displayNodeToSensorsMap(nodeToSensorsMap);
        end

        function displayNodeToSensorsMap(app, nodeToSensorsMap)
            % Get all node numbers (keys) from the map
            nodeNumbers = keys(nodeToSensorsMap);
            
            fprintf('\n=== Contents of nodeToSensorsMap ===\n');
            fprintf('Total nodes: %d\n\n', length(nodeNumbers));
            
            % Loop through each node
            for i = 1:length(nodeNumbers)
                node = nodeNumbers{i};
                nodeData = nodeToSensorsMap(node);
                
                fprintf('Node %d:\n', node);
                
                % Display x-direction sensor if it exists
                if ~isempty(nodeData.x)
                    fprintf('  x-direction: Sensor %d\n', nodeData.x);
                else
                    fprintf('  x-direction: [no sensor]\n');
                end
                
                % Display y-direction sensor if it exists
                if ~isempty(nodeData.y)
                    fprintf('  y-direction: Sensor %d\n', nodeData.y);
                else
                    fprintf('  y-direction: [no sensor]\n');
                end
                
                % Display z-direction sensor if it exists
                if ~isempty(nodeData.z)
                    fprintf('  z-direction: Sensor %d\n', nodeData.z);
                else
                    fprintf('  z-direction: [no sensor]\n');
                end
                
                fprintf('\n'); % Add space between nodes
            end
            fprintf('=== End of map contents ===\n\n');
        end

        function plotNodeData3D(app, nodeNumber)
            % Get the reverse mapping
            nodeToSensorsMap = app.nodeToSensorsMap;
            
            if ~isKey(nodeToSensorsMap, nodeNumber)
                error('Node %d not found in sensor mapping', nodeNumber);
            end
            
            % Get the sensor channels for each direction
            sensors = nodeToSensorsMap(nodeNumber);
            xChannel = sensors.x;
            yChannel = sensors.y;
            zChannel = sensors.z;

            disp(nodeNumber);
            disp('Channels');
            disp(xChannel);
            disp(yChannel);
            disp(zChannel);
            
            % Check which directions have data
            hasX = ~isempty(xChannel);
            hasY = ~isempty(yChannel);
            hasZ = ~isempty(zChannel);
            
            limitsMatrix = zeros(2,3);

            % Plot the available data
            if hasX
                plot(app.Signal_xAchse, app.timeVector, app.dataSensors(:, xChannel), "r");
                ylabel(app.Signal_xAchse, sprintf('Beschleunigung\nin x-Richtung \n[m/s²]'));
                limitsMatrix(:,1) = [min(app.dataSensors(:, xChannel)) max(app.dataSensors(:, xChannel))];
            else
                cla(app.Signal_xAchse);
                ylabel(app.Signal_xAchse, 'No x-direction data');
            end
            %
            if hasY
                plot(app.Signal_yAchse, app.timeVector, app.dataSensors(:, yChannel), "b");
                ylabel(app.Signal_yAchse, sprintf('Beschleunigung\nin y-Richtung \n[m/s²]'));
                limitsMatrix(:,2) = [min(app.dataSensors(:, yChannel)) max(app.dataSensors(:, yChannel))];
            else
                cla(app.Signal_yAchse);
                ylabel(app.Signal_yAchse, 'No y-direction data');
            end
            
            if hasZ
                plot(app.Signal_zAchse, app.timeVector, app.dataSensors(:, zChannel), "g");
                ylabel(app.Signal_zAchse, sprintf('Beschleunigung\nin z-Richtung \n[m/s²]'));
                limitsMatrix(:,3) = [min(app.dataSensors(:, zChannel)) max(app.dataSensors(:, zChannel))];
            else
                cla(app.Signal_zAchse);
                ylabel(app.Signal_zAchse, 'No z-direction data');
            end
            
            % Rest of your plotting code (limits, titles, etc.)
            % ...

            disp(limitsMatrix);
            % Berechnung der gemeinsamen x-Achsen-Limits
            minX = min(app.timeVector);  % Minimaler x-Wert
            maxX = max(app.timeVector);  % Maximaler x-Wert

            % Setze die X-Limits für alle Plots
            xLimitsSig = [minX, maxX];
            app.Signal_xAchse.XLim = xLimitsSig;
            app.Signal_yAchse.XLim = xLimitsSig;
            app.Signal_zAchse.XLim = xLimitsSig;

            % Einheitliche Y-Achsen-Limits (manuell oder automatisch berechnen)
            yMin = min(limitsMatrix,[],'all');
            yMax = max(limitsMatrix,[],'all');
            yLimits = [yMin, yMax]; % Einheitliche Y-Limits

            % Setze Y-Limits für alle Achsen
            app.Signal_xAchse.YLim = yLimits;
            app.Signal_yAchse.YLim = yLimits;
            app.Signal_zAchse.YLim = yLimits;

            grid(app.Signal_xAchse, 'on');
            grid(app.Signal_yAchse, 'on');
            grid(app.Signal_zAchse, 'on');
            
            % Titel hinzufügen
            title(app.Signal_xAchse, 'Schwingungsdaten');


            % Frequency spectrum plots
            axes(app.AxesFrequenzspektrum); % Aktiviere die Achse
            cla(app.AxesFrequenzspektrum);
            hold(app.AxesFrequenzspektrum, 'on');
            
            if hasX
                [f, amplitudeX] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:, xChannel));
                plot(app.AxesFrequenzspektrum, f, amplitudeX, "r", 'DisplayName', 'X-Richtung');
            end
            
            if hasY
                [~, amplitudeY] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:, yChannel));
                plot(app.AxesFrequenzspektrum, f, amplitudeY, "b", 'DisplayName', 'Y-Richtung');
            end
            
            if hasZ
                [~, amplitudeZ] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:, zChannel));
                plot(app.AxesFrequenzspektrum, f, amplitudeZ, "g", 'DisplayName', 'Z-Richtung');
            end
            
            hold(app.AxesFrequenzspektrum, 'off');

            % Setze die X-Limits
            xLimitsFreq = [min(f) max(f)];
            app.AxesFrequenzspektrum.XLim = xLimitsFreq;

            % Beschriftung und Legende hinzufügen
            xlabel(app.AxesFrequenzspektrum, 'Frequenz (Hz)');
            ylabel(app.AxesFrequenzspektrum, 'Amplitude');
            legend(app.AxesFrequenzspektrum, 'show'); % Zeige die Legende
            set(app.AxesFrequenzspektrum, 'YScale', 'log');         % Logarithmische Skala
            set(app.AxesFrequenzspektrum, 'YMinorGrid', 'off');      % Aktiviert Hilfslinien zwischen Hauptgittern
            set(app.AxesFrequenzspektrum, 'YMinorTick', 'off');      % Zeigt kleine Tick-Marks
            grid(app.AxesFrequenzspektrum, 'on');                   % Gitter aktivieren
            hold(app.AxesFrequenzspektrum, 'off'); % Beende das Halten der Achse

            % Setzen der Limits für die Slider
            app.SliderSignale.Limits = xLimitsSig;
            app.SliderFrequenzspektrum.Limits = xLimitsFreq;

            % Setzen der Werte (auf Maximalbereich)
            app.SliderSignale.Value = xLimitsSig; 
            app.SliderFrequenzspektrum.Value = xLimitsFreq; 
            % ... rest of frequency plot code
        end

        % Methode Stellt die Daten der Sensoren als Plot dar. Die
        % Darstellung erfolt sowohl in der Zeit, als auch in der
        % Frequenzdomäne.Es werden alle Koordinatenrichtungen (x,y,z) 
        % angezeigt. 
        function plotSensorData3D(app, i)
            
            % Plots der reinen Signale

            % Berechnung der gemeinsamen x-Achsen-Limits
            minX = min(app.timeVector);  % Minimaler x-Wert
            maxX = max(app.timeVector);  % Maximaler x-Wert

            % Setze die X-Limits für alle Plots
            xLimitsSig = [minX, maxX];
            app.Signal_xAchse.XLim = xLimitsSig;
            app.Signal_yAchse.XLim = xLimitsSig;
            app.Signal_zAchse.XLim = xLimitsSig;

            % Einheitliche Y-Achsen-Limits (manuell oder automatisch berechnen)
            yMin = min(min(app.dataSensors(:, i:i+2)));
            yMax = max(max(app.dataSensors(:, i:i+2)));
            yLimits = [yMin, yMax]; % Einheitliche Y-Limits

            % Setze Y-Limits für alle Achsen
            app.Signal_xAchse.YLim = yLimits;
            app.Signal_yAchse.YLim = yLimits;
            app.Signal_zAchse.YLim = yLimits;

            % Plot Signal
            plot(app.Signal_xAchse,app.timeVector,app.dataSensors(:, (i)),"r");
            plot(app.Signal_yAchse,app.timeVector,app.dataSensors(:, (i+1)),"b");
            plot(app.Signal_zAchse,app.timeVector,app.dataSensors(:, (i+2)),"g");

            % Achsenbeschriftung und Gitter
            xlabel(app.Signal_zAchse, 'Zeit [s]');
            ylabel(app.Signal_xAchse, sprintf('Beschleunigung\nin x-Richtung \n[m/s²]'));
            ylabel(app.Signal_yAchse, sprintf('Beschleunigung\nin y-Richtung \n[m/s²]'));
            ylabel(app.Signal_zAchse, sprintf('Beschleunigung\nin z-Richtung \n[m/s²]'));
            grid(app.Signal_xAchse, 'on');
            grid(app.Signal_yAchse, 'on');
            grid(app.Signal_zAchse, 'on');

             % Titel hinzufügen (falls gewünscht)
            title(app.Signal_xAchse, 'Schwingungsdaten');

            % Frequenzspektrun

            % Frequenzspektrum-Plot vorbereiten
            axes(app.AxesFrequenzspektrum); % Aktiviere die Achse
            cla(app.AxesFrequenzspektrum); % Lösche den vorherigen Inhalt (falls nötig)
            hold(app.AxesFrequenzspektrum, 'on'); % Halte die Achse offen für mehrere Plots

            % Plot für X-Daten
            [f, amplitudeX] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:,i));
            plot(app.AxesFrequenzspektrum, f,amplitudeX, "r", 'DisplayName', 'X-Richtung');
            
            % Plot für Y-Daten
            [~, amplitudeY] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:,i+1));
            plot(app.AxesFrequenzspektrum, f, amplitudeY, "b", 'DisplayName', 'Y-Richtung');
            
            % Plot für Z-Daten
            [~, amplitudeZ] = calculateFrequencySpectrum(app.timeVector, app.dataSensors(:,i+2));
            plot(app.AxesFrequenzspektrum, f, amplitudeZ, "g", 'DisplayName', 'Z-Richtung');
            
            % Setze die X-Limits
            xLimitsFreq = [min(f) max(f)];
            app.AxesFrequenzspektrum.XLim = xLimitsFreq;

            % Beschriftung und Legende hinzufügen
            xlabel(app.AxesFrequenzspektrum, 'Frequenz (Hz)');
            ylabel(app.AxesFrequenzspektrum, 'Amplitude');
            legend(app.AxesFrequenzspektrum, 'show'); % Zeige die Legende
            set(app.AxesFrequenzspektrum, 'YScale', 'log');         % Logarithmische Skala
            set(app.AxesFrequenzspektrum, 'YMinorGrid', 'off');      % Aktiviert Hilfslinien zwischen Hauptgittern
            set(app.AxesFrequenzspektrum, 'YMinorTick', 'off');      % Zeigt kleine Tick-Marks
            grid(app.AxesFrequenzspektrum, 'on');                   % Gitter aktivieren
            hold(app.AxesFrequenzspektrum, 'off'); % Beende das Halten der Achse

            % Setzen der Limits für die Slider
            app.SliderSignale.Limits = xLimitsSig;
            app.SliderFrequenzspektrum.Limits = xLimitsFreq;

            % Setzen der Werte (auf Maximalbereich)
            app.SliderSignale.Value = xLimitsSig; 
            app.SliderFrequenzspektrum.Value = xLimitsFreq; 
        end

        % Plotten der Der Stärke der Frequenzen in übergebene UiAxes
        function plotPSD(app, uiAxes)            
            % Berechne XLimits für Plot
            xLimits = [min(app.frequencies) max(app.frequencies)];

            % Beschriftung und Legende hinzufügen
            % Plot first singular values of the PSD matrix on the specified UIAxes
            cla(uiAxes);
            hold(uiAxes, 'on');                 % Activate hold on the UIAxes
            uiAxes.XLim = xLimits;              % Setze die X-Limits
            plot(uiAxes, app.frequencies, app.singularValues);
            xlabel(uiAxes, 'Frequenz [Hz]');   % Label x-axis on uiAxes
            ylabel(uiAxes, 'Singulärwerte [(m/s²)²/Hz]'); % Label y-axis on uiAxes
            set(uiAxes, 'YScale', 'log');         % Logarithmische Skala
            set(uiAxes, 'YMinorGrid', 'off');      % Aktiviert Hilfslinien zwischen Hauptgittern
            set(uiAxes, 'YMinorTick', 'off');      % Zeigt kleine Tick-Marks
            grid(uiAxes, 'on');                   % Gitter aktivieren

        end

        function plotSelectedPoints(app, uiAxes)

            % Holen der Variablen
            Fp = app.selectedFrequencies;
            F = app.frequencies;
            s1 = app.singularValues;

            cla(uiAxes);      % , 'reset'
            hold(uiAxes, 'on');    
            plot(uiAxes, F, s1);
            % Nummereiren der Peaks
            for I=1:size(Fp,1)
                scatter(uiAxes, Fp(I,2),s1(Fp(I,1)),'MarkerEdgeColor','g','MarkerFaceColor','g')
                text(uiAxes, Fp(I,2), s1(Fp(I,1))*1.05, mat2str(I))
            end
        end

        function plotMACModes(app, uIAxes)
            
            % Durchlaufe alle Frequenzgruppen und stelle die Entsprechenden
            % Daten im Plot dar
            for i = 1:length(app.frequenzGruppen)
                indices = cell2mat(app.frequenzGruppen{i}); % Alle Indizes der aktuellen Gruppe extrahieren
                currentValues = app.singularValues(indices); % Werte basierend auf Indizes holen
                currentTimes = app.frequencies(indices);     % Zeitwerte basierend auf Indizes holen
            
                % Plotten
                plot(uIAxes, currentTimes, currentValues, 'r', 'LineWidth', 1.5);
            end

        end

        function showSelectedModesInTable(app, selModes, uiTable, option)
            % Option 1 = Mode
            % Option 2 = Enhanced Mode
           
            % Leeren der UITable
            uiTable.Data = {};               % Entfernt alle alten Daten aus der Tabelle
            uiTable.ColumnName = {};         % Setzt die Spaltennamen auf leer
            uiTable.RowName = {};            % Setzt die Zeilenbeschriftungen auf leer

            % Unicode-Zeichen für tiefgestellte Zahlen (0-9)
            unicodeSubscript = {'₀', '₁', '₂', '₃', '₄', '₅', '₆', '₇', '₈', '₉'};
            
            % Funktion, um tiefgestellte Zahlen zu erstellen
            toSubscript = @(num) strjoin(arrayfun(@(d) unicodeSubscript{str2double(d)+1}, ...
               strsplit(num2str(num), ''), 'UniformOutput', false), '');
            
            % Befülle die Tabelle mit Eigenvektoren
            if option == 1
                spaltenNamen = arrayfun(@(i) sprintf('Mode %d', i), 1:size(app.selectedFrequencies,1), 'UniformOutput', false);
            elseif option == 2
                spaltenNamen = arrayfun(@(i) sprintf('Mode %d', i), 1:size(app.selectedFrequencies,1), 'UniformOutput', false);
            end

            % Zeilenbeschriftungen generieren
            zeilenNamen = cell(app.anzahlSensoren*3, 1); % Platz für x, y, z jeder Richtung
            for i = 1:app.anzahlSensoren
               zeilenNamen{(i-1)*3 + 1} = ['x' toSubscript(i)]; % x_i
               zeilenNamen{(i-1)*3 + 2} = ['y' toSubscript(i)]; % y_i
               zeilenNamen{(i-1)*3 + 3} = ['z' toSubscript(i)]; % z_i
            end
               
            % Setze die Data- und ColumnName-Eigenschaften der UITable
            uiTable.Data = selModes;         % Füllt die UITable mit der Matrix selectedModes
            uiTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.
            uiTable.RowName= zeilenNamen;

        end

        function updateListboxFrequencies(app, selFrequencies, listBox)

            % Passe Dimension an, falls notwendig
            if size(selFrequencies,2) == 2
                selFrequencies = selFrequencies(:,2);
            end
            
            % Leeren der Listbox
            listBox.Items = {};
            
            % Ermöglichen der Auswahl eines Sensors und befüllen der Listbox
            % Erstelle die Items für die ListBox
            neueItems = cell(1, length(selFrequencies));  % Initialisiere eine Zelle für die Items
            for i = 1:length(selFrequencies)
                neueItems{i} = sprintf('%.3f', selFrequencies(i));  %  Befülle mit der jew. Frequenz
            end
            listBox.Items = neueItems;

            if ~isempty(listBox.Items)
                listBox.Value = listBox.Items{1};  % Wählt das erste Item aus, falls die Liste nicht leer ist
            end

        end

        function showSelectedFrequenciesInTable(app, selFrequencies, uiTable)

            % Leeren der UITable
            uiTable.Data = {};               % Entfernt alle alten Daten aus der Tabelle
            uiTable.ColumnName = {};         % Setzt die Spaltennamen auf leer
            uiTable.RowName = {};            % Setzt die Zeilenbeschriftungen auf leer

            % Befülle die Tabelle mit Frequenzen
            spaltenNamen = arrayfun(@(i) sprintf('Frequenz %d', i), 1:size(selFrequencies,1), 'UniformOutput', false);
            
            % Setze die Data- und ColumnName-Eigenschaften der UITable
            uiTable.Data = selFrequencies(:,2)';         % Füllt die UITable mit der Matrix selectedModes
            uiTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.

        end

        function showResultsFDD(app)

           % Zuweisen der Variablen 
           Frq = app.selectedFrequencies;

           % Ermöglichen der Auswahl eines Sensors und befüllen der Listbox
           % Erstelle die Items für die ListBox
           neueItems = cell(1, size(Frq,1));  % Initialisiere eine Zelle für die Items
           for i = 1:size(Frq,1)
               neueItems{i} = sprintf('%.3f', Frq(i,2));  %  Befülle mit der jew. Frequenz
           end
           app.UrspruenglicheFrequenzenListBox.Items = neueItems;

           if ~isempty(app.UrspruenglicheFrequenzenListBox.Items)
               app.UrspruenglicheFrequenzenListBox.Value = app.UrspruenglicheFrequenzenListBox.Items{1};  % Wählt das erste Item aus, falls die Liste nicht leer ist
           end

           % Unicode-Zeichen für tiefgestellte Zahlen (0-9)
           unicodeSubscript = {'₀', '₁', '₂', '₃', '₄', '₅', '₆', '₇', '₈', '₉'};
            
           % Funktion, um tiefgestellte Zahlen zu erstellen
           toSubscript = @(num) strjoin(arrayfun(@(d) unicodeSubscript{str2double(d)+1}, ...
               strsplit(num2str(num), ''), 'UniformOutput', false), '');
           % Befülle die Tabelle mit Eigenvektoren
           spaltenNamen = arrayfun(@(i) sprintf('Eigenvektor %d', i), 1:size(Frq,1), 'UniformOutput', false);
           % Zeilenbeschriftungen generieren
           zeilenNamen = cell(app.anzahlSensoren*3, 1); % Platz für x, y, z jeder Richtung
           for i = 1:app.anzahlSensoren
               zeilenNamen{(i-1)*3 + 1} = ['x' toSubscript(i)]; % x_i
               zeilenNamen{(i-1)*3 + 2} = ['y' toSubscript(i)]; % y_i
               zeilenNamen{(i-1)*3 + 3} = ['z' toSubscript(i)]; % z_i
           end
           
           
           % Setze die Data- und ColumnName-Eigenschaften der UITable
           app.AnimationTable.Data = app.selectedModes;         % Füllt die UITable mit der Matrix selectedModes
           app.AnimationTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.
           app.AnimationTable.RowName= zeilenNamen;

           % Setze die Data- und ColumnName-Eigenschaften der
           % EigenvektorenTabelle
           app.ModesTable.Data = app.selectedModes;         % Füllt die UITable mit der Matrix selectedModes
           app.ModesTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.
           app.ModesTable.RowName= zeilenNamen;

           % Befülle die Tabelle mit Frequenzen
           spaltenNamen = arrayfun(@(i) sprintf('Frequenz %d', i), 1:size(Frq,1), 'UniformOutput', false);

           % Setze die Data- und ColumnName-Eigenschaften der UITable
           app.FrequenzenTable.Data = Frq(:,2)';         % Füllt die UITable mit der Matrix selectedModes
           app.FrequenzenTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.
        end

        function selectPSD(app,i)
            psd_single_sided = zeros(length(app.singularValues), 1);
            indices = cell2mat(app.frequenzGruppen{i}); % Alle Indizes der aktuellen Gruppe extrahieren
            psd_single_sided(indices) = app.singularValues(indices); % Werte basierend auf Indizes holen
            app.psd_auswahl = psd_single_sided;
        end

        function fftToSignal(app, y_single)

            n = app.anzahlDatenpunkte; % Original number of samples
            
            Y_half = y_single; 
            N_half = length(Y_half);  % Length of single-sided spectrum
            N_full = 2 * (N_half - 1); % Full FFT length (assumes even case by default)

            % Check if the original FFT size was odd
            if mod(N_full, 2) == 1
                N_full = N_full + 1;
            end

            % scale Length of timevector by Nhalf and nsinglesidedFft
            T = app.dauerMessung * N_full / app.anzahlDatenpunkte;

            % Compute IFFT
            X_rec = ifft(Y_half, n, 'symmetric');
            
            % Compute new time vector
            t_y = linspace(0, T, n);            

            % Überprüfe ob Auflösung des signals ausreichend ist
            f_rec = app.calculateFrequencyOfSignal(X_rec,t_y);
            nerf = round(f_rec * T * 20);
            if nerf > n
                % Compute new IFFT
                X_rec = ifft(Y_half, nerf, 'symmetric');
                % Compute new time vector
                t_y = linspace(0, T, nerf);
                disp('höhere Auflösung des Signals berechnet')
            end

            app.signal_rec = X_rec / max(abs(X_rec)); % Normieren der Größten Amplitude auf 1
            app.t_rec = t_y;

        end


        function freq = calculateFrequencyOfSignal(app,timeSignal,timeVector)
            % Berechnung der Auftretenden Frequenz
            [~,locs]=findpeaks(timeSignal);
            natPeriod = diff(timeVector(locs));
            freq = length(natPeriod)/sum(natPeriod);
        end
            
        function plotReconstructionOfSignal(app, i)
            
            app.selectPSD(i);
            app.fftToSignal(app.psd_auswahl);

            t = app.t_rec;
            timeSignal = app.signal_rec;
            psd_single_sided = app.psd_auswahl;

            % Plotten des Ausgewählten Frequenzspektrums
            xLimits1 = [min(app.frequencies) max(app.frequencies)];
            app.UIAxes_2.XLim = xLimits1;
            plot(app.UIAxes_2, app.frequencies, psd_single_sided);
            title(app.UIAxes_2, ['SVD der PSD | SDOF-System ' num2str(i)]);
            xlabel(app.UIAxes_2, 'Frequenz [Hz]');
            ylabel(app.UIAxes_2, 'Singulärwerte [(m/s²)²/Hz]');
            set(app.UIAxes_2, 'YScale', 'log');         % Logarithmische Skala
            set(app.UIAxes_2, 'YMinorGrid', 'off');      % Aktiviert Hilfslinien zwischen Hauptgittern
            set(app.UIAxes_2, 'YMinorTick', 'off');      % Zeigt kleine Tick-Marks
            grid(app.UIAxes_2, 'on');                   % Gitter aktivieren
    
            % Plotten des Rekonstruierten Signals 
            xLimits2 = [min(t) max(t)];
            app.UIAxes_3.XLim = xLimits2;
            plot(app.UIAxes_3, t, timeSignal);
            title(app.UIAxes_3, ['Normalisierte Autokorrelationsfunktion | SDOF-System ' num2str(i)]);
            xlabel(app.UIAxes_3, 'Zeit [s]');
            ylabel(app.UIAxes_3, 'Amplitude [(m/s²)²]');

            % Einstellen der Slider
            app.ModeGroupSlider.Limits = xLimits1;
            app.SignalSlider.Limits = xLimits2;
            app.ModeGroupSlider.Value = xLimits1;
            app.SignalSlider.Value = xLimits2;
           
            % ----------- Plot für Dämpfung -----------------

            % Berechnung der Peaks
            [val,loc] = findpeaks(timeSignal);

            % Rekonstruiertes Signal Plot für diese Gruppe
            cla(app.UIAxesSignalMitPeaks);
            hold(app.UIAxesSignalMitPeaks, 'on');
            app.UIAxesSignalMitPeaks.XLim = xLimits2;
            plot(app.UIAxesSignalMitPeaks, t, timeSignal, 'b', 'DisplayName', 'Rekonstruiertes Signal');
            plot(app.UIAxesSignalMitPeaks, t(loc), val, 'r', 'LineWidth', 1.5, 'DisplayName', 'Umhüllende der Peaks');
            legend(app.UIAxesSignalMitPeaks, 'show');
            title(app.UIAxesSignalMitPeaks, ['Normalisierte Autokorrelationsfunktion | SDOF-System ' num2str(i)]);
            xlabel(app.UIAxesSignalMitPeaks, 'Zeit [s]');
            ylabel(app.UIAxesSignalMitPeaks, 'Amplitude [(m/s²)²]');
            grid(app.UIAxesSignalMitPeaks, 'on');         
            hold(app.UIAxesSignalMitPeaks, 'off');

            % Einstellen Slider
            app.Slider.Limits = xLimits2;
            app.Slider.Value = [xLimits2(1) xLimits2(2)/2];

            app.plotRegressionDaempfung(i);
   
        end

        function erstelleDateiFuerDaten(app, t, signal, xLimits)
            % Filtern der Daten basierend auf xLimits2
            t_filtered = t(t >= xLimits(1) & t <= xLimits(2));
            signal_filtered = signal(t >= xLimits(1) & t <= xLimits(2));
            
            % Kombinieren der gefilterten Daten in eine Matrix
            filteredData = [t_filtered(:), signal_filtered(:)]; % Sicherstellen, dass Spaltenvektoren erstellt werden
            
            % Speichern der Daten in eine Datei
            filename = 'RekonstruiertesSignalFragment.txt';
            writematrix(filteredData, filename, 'Delimiter', '\t', 'FileType', 'text');
            
            disp(['Daten wurden erfolgreich in ', filename, ' gespeichert.']);
        end

        function exportFrequenzspektrum(app, frequencies, psd_single_sided)
            
            % Kombinieren der gefilterten Daten in eine Matrix (Frequenzen und PSD)
            filteredData = [frequencies(:), psd_single_sided(:)]; % Sicherstellen, dass Spaltenvektoren erstellt werden
            
            % Speichern der Daten in einer Datei
            filename = 'FrequenzenMitPSD.txt';
            writematrix(filteredData, filename, 'Delimiter', '\t', 'FileType', 'text');
            
            disp(['Daten wurden erfolgreich in ', filename, ' gespeichert.']);
        end


        function plotRegressionDaempfung(app, i)
            
            % Reconstruieren des Signals
            app.selectPSD(i);
            app.fftToSignal(app.psd_auswahl);

            % Holen der Limits Vom Slider
            x_Limits = app.Slider.Value;

            t = app.t_rec;
            timeSignal = app.signal_rec;
            
            % Indizes finden, die innerhalb der Grenzen liegen
            indices = (t >= x_Limits(1)) & (t <= x_Limits(2));
            
            % Kürzen der Vektoren
            timeSignal_kurz = timeSignal(indices);
            t_kurz = t(indices);

            % Berechnung der Peaks
            [val,loc] = findpeaks(timeSignal_kurz);

            % Rekonstruiertes Signal Plot für diese Gruppe
            cla(app.UIAxesSignalMitPeaks);
            hold(app.UIAxesSignalMitPeaks, 'on');
            xLimits2 = [min(t) max(t)];
            app.UIAxesSignalMitPeaks.XLim = xLimits2;
            plot(app.UIAxesSignalMitPeaks, t, timeSignal, 'b', 'DisplayName', 'Rekonstruiertes Signal');
            plot(app.UIAxesSignalMitPeaks, t_kurz(loc), val, 'r', 'LineWidth', 1.5, 'DisplayName', 'Umhüllende der Peaks');
            xline(app.UIAxesSignalMitPeaks, x_Limits(1), 'r--', 'LineWidth', 1.5, 'DisplayName', 'Linke Grenze Auswahlbereich');
            xline(app.UIAxesSignalMitPeaks, x_Limits(2), 'g--', 'LineWidth', 1.5, 'DisplayName', 'Rechte Grenze Auswahlbereich');
            legend(app.UIAxesSignalMitPeaks, 'show')
            title(app.UIAxesSignalMitPeaks, ['Normalisierte Autokorrelationsfunktion | SDOF-System ' num2str(i)]);
            xlabel(app.UIAxesSignalMitPeaks, 'Zeit (s)');
            ylabel(app.UIAxesSignalMitPeaks, 'Amplitude [(m/s²)²]');
            grid(app.UIAxesLogPeaks, 'on');
            hold(app.UIAxesLogPeaks, 'off');     

            % app.calculateAndPlotDaempfungsgrad(timeSignal_kurz);
            
            % ********** Berechnung mit Regression für Positive Peaks des Signals **********
            [val,~] = findpeaks(abs(timeSignal_kurz));
            idx = 1:length(val);
            coeff = polyfit(idx, 2*log(val), 1); % 1 steht für eine Gerade (lineare Regression)
            m = coeff(1);                     % Steigung
            % b = coeff(2);                     % Achsenabschnitt
            % Berechnung des Dämpfungsgrades
            zeta = m / sqrt(m^2 + 4 * pi^2);
            zeta = abs(zeta);
            
            % Daten und Regressionslinie
            y_fit = polyval(coeff, idx);

            app.MitRegressionermittelteDmpfungEditField.Value = zeta;

            % Peaks Logarithmisiert
            cla(app.UIAxesLogPeaks);
            hold(app.UIAxesLogPeaks, 'on');
            plot(app.UIAxesLogPeaks, idx, 2*log(val), 'bo');
            plot(app.UIAxesLogPeaks, idx, y_fit, 'r-', 'LineWidth', 1.5); % Regressionslinie
            xlabel(app.UIAxesLogPeaks, 'Peak Nummer');
            ylabel(app.UIAxesLogPeaks, '[2*ln(r_k)]');
            title(app.UIAxesLogPeaks, 'Logarithmische Darstelung der Peaks');
            grid(app.UIAxesLogPeaks, 'on');
            hold(app.UIAxesLogPeaks, 'off');

            % ****** Berechnung Logatithmisches Decrement ********
            % Berechnung der Durchschnittlichen Dämpfung
            delta = 2*log(val(1:end-1) ./ val(2:end));
            % Durchschnittliches logarithmisches Dekrement
            delta_avg = mean(delta);
            % Dämpfungsgrad berechnen
            zeta_estimated = delta_avg / sqrt(4 * pi^2 + delta_avg^2);
            
            zeta_All = zeros(length(delta),1);
            
            for i = 1:length(delta)
                zeta_All(i) = delta(i) / sqrt(delta(i)^2 + 4 * pi^2);
            end    

            app.GemittelteDmpfungEditField.Value = zeta_estimated;

            % Plotten
            histogram(app.UIAxesHistogramDurchschnitt, zeta_All,'Normalization', 'pdf'); % Normiertes Histogramm für PDF
            hold(app.UIAxesHistogramDurchschnitt, 'on');
            
            % Anpassen an Normalverteilung
            pd = fitdist(zeta_All, 'Normal'); % 'Normal' für Normalverteilung
            mu = pd.ParameterValues(1);                % Mittelwert
            sigma = pd.ParameterValues(2);           % Standardabweichung
            
            % Optimierte Verteilung plotten
            x = linspace(min(zeta_All), max(zeta_All), 1000); % Wertebereich für die Verteilung
            y = pdf(pd, x);                          % Wahrscheinlichkeitsdichtefunktion
            plot(app.UIAxesHistogramDurchschnitt, x, y, 'r-', 'LineWidth', 2);        % Verteilung plotten
            % Dummy-Linie für Legende erzeugen
            plot(app.UIAxesHistogramDurchschnitt, NaN, NaN, 'w'); % weiße Linie (unsichtbar)
            plot(app.UIAxesHistogramDurchschnitt, NaN, NaN, 'w'); % weiße Linie (unsichtbar)
            
            % Achsenbeschriftung
            xlabel(app.UIAxesHistogramDurchschnitt, 'Wertebereich');
            ylabel(app.UIAxesHistogramDurchschnitt, 'Absolute Häufigkeit');
            title(app.UIAxesHistogramDurchschnitt, 'Histogramm der Dämpfungsgrade (Peak-zu-Peak-Methode)');
            legend(app.UIAxesHistogramDurchschnitt, 'Histogramm', 'Optimierte Verteilung', ...
                sprintf('\x03bc = %.4f', mu), ...  
                sprintf('\x03c3 = %.4f', sigma), ...
                'Location', 'northeast'); % μ (Unicode: U+03BC)  % σ (Unicode: U+03C3)
            hold(app.UIAxesHistogramDurchschnitt, 'off');
        end


        function erstelleTabDaempfung(app, uiTable)
            % Initialize with NaN values formatted as strings
            data = repmat({sprintf('%.5f', NaN)}, size(app.selectedFrequencies,1), 2);
            
            % Set up the table
            uiTable.Data = data;  % Cell array of strings
            uiTable.RowName = arrayfun(@(i) sprintf('Frequenz %d', i), 1:size(app.selectedFrequencies,1), 'UniformOutput', false);
            uiTable.ColumnName = {['Daempfung mit' newline ' Durchschnitt'], ['Daempfung nach' newline ' Regression']};
            
            % Set column format to char (for string display)
            uiTable.ColumnFormat = {'char', 'char'};
        end


        function showEFrequenciesInTable(app, selFrequencies, uiTable)
            % Create data with numeric columns converted to formatted strings
            freq_fdd = arrayfun(@(x) sprintf('%.4f', x), app.selectedFrequencies(:,2), 'UniformOutput', false);
            freq_efdd = arrayfun(@(x) sprintf('%.4f', x), app.eFrequencies(:), 'UniformOutput', false);
            damping = repmat({sprintf('%.5f', NaN)}, length(selFrequencies), 1);
            
            dataMatrix = [freq_fdd, freq_efdd, damping];
            
            % Set up the table
            uiTable.Data = dataMatrix;
            uiTable.RowName = arrayfun(@(i) sprintf('Nr. %d', i), 1:length(selFrequencies), 'UniformOutput', false);
            uiTable.ColumnName = {'Frequenz FDD', 'Frequenz eFDD', 'Dämpfungsgrad'};
            uiTable.ColumnFormat = {'numeric', 'numeric', 'char'};
        end

        function onMouseClick(app, src, event)
        
            % Mausposition in der gesamten App ermitteln
            app.mousePos = app.UIFigure.CurrentPoint;
            app.peakSelection = 0; % Reset _> Auswahl neutrale
        end

        % TODO: Close Request Function
        function UIFigureCloseRequest(app)
            % Schleife sauber beenden
            app.selectionProcess = false;
            app.setAnimationStatus(false);
            % app.animationStatus = false;
            disp('Closerequest Function Activated')
            disp('Get Animation Status')
            disp(app.getAnimationStatus)
            pause(2);
            disp('Get Animation Status nach pause')
            disp(app.getAnimationStatus)            
            % App-Fenster schließen
            delete(app);
        end
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Überprüfe ob alle erf. Toolboxen installiert sind
            requiredToolboxes = {'Signal_Toolbox', 'Image_Toolbox', 'Statistics_Toolbox'};
            for i = 1:length(requiredToolboxes)
                if ~license('test', requiredToolboxes{i})
                    warning(['Die erforderliche Toolbox ' requiredToolboxes{i} ' ist nicht installiert oder lizenziert.']);
                end
            end
            
            % % Maximieren des App-Fensters, wobei die Titel- und Steuerleiste sichtbar bleibt
            drawnow;
            app.UIFigure.WindowState = 'maximized';

             % Zuweisung der CloseRequestFcn
            app.UIFigure.CloseRequestFcn = @(src, event) app.UIFigureCloseRequest;
            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.peakSelection = 1;
        end

        % Button pushed function: DeselectButton
        function DeselectButtonPushed(app, event)
            app.peakSelection = 2;
        end

        % Button pushed function: ConnectionButton
        function ConnectionButtonPushed(app, event)
           [filename,path] = uigetfile('*.txt');
           app.filePathVerbindungSensoren = fullfile(path,filename);
           figure(app.UIFigure);
           app.ConnectionEditField.Value = 'File selected';
           app.ConnectionLamp.Color = 'g';  

           % Lesen und Speichern der Daten
           app.connectionSensors = readmatrix(app.filePathVerbindungSensoren); % 'NumHeaderLines', 1

           % Anzeigen der Daten in Tabelle
           spaltenNamen = {'Start Knoten', 'End Knoten'};

           % Setze die Data- und ColumnName-Eigenschaften der UITable
           app.SensorConnectionTable.Data = app.connectionSensors;
           app.SensorConnectionTable.ColumnName = spaltenNamen;
           
           % Zeige Grafik, wenn ausreichend Daten vorhanden
           if ~isempty(app.positionSensors)
               % disp('Kannten in der Schleife')
               axes(app.PositionAxes_2);
               visualiseSensors(app, app.positionSensors, app.connectionSensors, app.PositionAxes_2);
               visualiseSensors(app, app.positionSensors, app.connectionSensors, app.MovementAxes);
           end
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            app.setAnimationStatus(true);
            statusAuswahl = app.selectedAnimation;
            modesToBeShown = []; 
            % index = null;
            % Auswahl der Darzustellenden Frequenz
            if statusAuswahl == 1
                selectedItem = app.UrspruenglicheFrequenzenListBox.Value;  % Aktuell ausgewähltes Element
                index = find(strcmp(app.UrspruenglicheFrequenzenListBox.Items, selectedItem));  % Index des Elements
                modesToBeShown = app.selectedModes;
                % Anzeigen der Aktiven Darstellung
                app.UrspruenglichLamp.Color = 'g';
                app.VerbessertLamp.Color = 'r';
            elseif statusAuswahl == 2
                selectedItem = app.VerbesserteFrequenzenListBox.Value;  % Aktuell ausgewähltes Element
                index = find(strcmp(app.VerbesserteFrequenzenListBox.Items, selectedItem));  % Index des Elements
                modesToBeShown = app.eModes;
                % Anzeigen der Aktiven Darstellung
                app.UrspruenglichLamp.Color = 'r';
                app.VerbessertLamp.Color = 'g';
            end

            % Zeige Differenz, falls erforderlich.
            if strcmp(app.DifferenzderModenButtonGroup.SelectedObject.Text, 'aktiv')
                modesToBeShown = app.eModes - app.selectedModes;
                % Gleichstellen der Frequenzauswahlen.
                app.UrspruenglicheFrequenzenListBox.Value = app.UrspruenglicheFrequenzenListBox.Items{index};
                app.VerbesserteFrequenzenListBox.Value = app.VerbesserteFrequenzenListBox.Items{index};
                % Anzeigen der Aktiven Darstellung
                app.UrspruenglichLamp.Color = 'b';
                app.VerbessertLamp.Color = 'b';
            end
            
            % Auslesen der des Winkels aus dem Plot
            app.perspective = app.MovementAxes.View;

            visualiseMovement(app, app.positionSensors, app.connectionSensors, modesToBeShown, app.MovementAxes, index, app.scale);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.setAnimationStatus(false);
        end

        % Value changed function: UrspruenglicheFrequenzenListBox
        function UrspruenglicheFrequenzenListBoxValueChanged(app, event)
            % selectedItem = app.UrspruenglicheFrequenzenListBox.Value;  % Aktuell ausgewähltes Element
            app.selectedAnimation = 1;
            % app.selectedItemFreq = find(strcmp(app.UrspruenglicheFrequenzenListBox.Items, selectedItem));  % Index des Elements
            app.StartAnimationButtonPushed;
        end

        % Value changed function: SkallierenderVerschiebungEditField
        function SkallierenderVerschiebungEditFieldValueChanged(app, event)
            app.scale = app.SkallierenderVerschiebungEditField.Value;
            app.StartAnimationButtonPushed;
        end

        % Value changed function: SensorenListBox
        function SensorenListBoxValueChanged(app, event)
            % Get the selected node number directly from ItemsData
            selectedNode = app.SensorenListBox.Value;
            
            disp('Ausgewählter Knoten:');
            disp(selectedNode);
            
            % Plot data for the selected node
            try
                app.plotNodeData3D(selectedNode);
            catch ME
                errordlg(sprintf('Fehler beim Plotten von Knoten %d: %s', selectedNode, ME.message), 'Plot-Fehler');
            end
        end

        % Value changing function: SliderSignale
        function SliderSignaleValueChanging(app, event)
            changingValue = event.Value;
            % Setze Y-Limits für alle Achsen
            app.Signal_xAchse.XLim = changingValue;
            app.Signal_yAchse.XLim = changingValue;
            app.Signal_zAchse.XLim = changingValue;

            x1 = app.Signal_xAchse.Children.XData;
            y1 = app.Signal_xAchse.Children.YData;
            y2 = app.Signal_yAchse.Children.YData;
            y3 = app.Signal_zAchse.Children.YData;
            % Finde die Indizes der x-Werte, die innerhalb von xLim liegen
            visibleIndices = x1 >= changingValue(1) & x1 <= changingValue(2);

            % Extrahiere die entsprechenden y-Werte
            visibleY1 = y1(visibleIndices);
            visibleY2 = y2(visibleIndices);
            visibleY3 = y3(visibleIndices);

            % Berechne minimale und maximale y-Werte für den Bereich
            yMin1 = min(visibleY1);
            yMax1 = max(visibleY1);

            yMin2 = min(visibleY2);
            yMax2 = max(visibleY2);
            
            yMin3 = min(visibleY3);
            yMax3 = max(visibleY3);
            
            % Bestimme den globalen Minimal- und Maximalwert
            yMin = min([yMin1, yMin2, yMin3]);
            yMax = max([yMax1, yMax2, yMax3]);

            yLimits = [yMin, yMax]; % Einheitliche Y-Limits

            % Setze Y-Limits für alle Achsen
            app.Signal_xAchse.YLim = yLimits;
            app.Signal_yAchse.YLim = yLimits;
            app.Signal_zAchse.YLim = yLimits;
        end

        % Value changing function: SliderFrequenzspektrum
        function SliderFrequenzspektrumValueChanging(app, event)
            changingValue = event.Value;
            app.AxesFrequenzspektrum.XLim = changingValue;
        end

        % Button pushed function: SensorDataConnectionButton
        function SensorDataConnectionButtonPushed(app, event)
            [filename,path] = uigetfile('*.txt');
            app.filePathVerbindungDatenSensoren = fullfile(path,filename);
            figure(app.UIFigure);
            app.SensorDataConnectionEditField.Value = 'File selected';
            app.SensorDataConnectionLamp.Color = 'g';  
            
            % Lesen und Speichern der Daten
            data = readtable(app.filePathVerbindungDatenSensoren);    
            % Separate Spalten für Sensoren und Knoten mit Richtung
            sensoren = data{:, 1};
            knotenMitRichtung = string(data{:, 2});  % Verwende string, um auch "x", "y", "z" zu speichern
            
            % Map initialisieren (Sensor -> Struktur mit Knoten und Richtung)
            app.sensorToNodeMap = containers.Map('KeyType', 'double', 'ValueType', 'any');
            
            % Map mit den Daten füllen
            for i = 1:length(sensoren)
                % Die Koordinatenrichtung extrahieren (letztes Zeichen)
                % richtung = knotenMitRichtung(i,end);  % Zum Beispiel "x", "y", "z"
                richtung = extractAfter(knotenMitRichtung(i), 1);  % Extrahiert den Buchstaben nach der Zahl (z.B. "x", "y", "z")

                % Der Knoten ist die Zahl ohne die Richtung
                knoten = str2double(extractBefore(knotenMitRichtung(i), 2));   % Extrahiert die Zahl vor dem Buchstaben (z.B. "2" aus "2x")

                % Struktur für den aktuellen Sensor
                app.sensorToNodeMap(sensoren(i)) = struct('Knoten', knoten, 'Richtung', richtung);
            end

            % Füllen der Tabelle

            % Extrahiere Schlüssel und Werte
            keys = app.sensorToNodeMap.keys();
            valuesList = values(app.sensorToNodeMap);
            
            % Initialisiere leere Zellen für die Knoten und Richtungen
            knoten = cell(length(valuesList), 1);
            richtung = cell(length(valuesList), 1);
            
            % Extrahiere die Knoten und Richtungen aus den Strukturen
            for i = 1:length(valuesList)
                knoten{i} = valuesList{i}.Knoten;  % Knoten aus der Struktur
                richtung{i} = valuesList{i}.Richtung;  % Richtung aus der Struktur
            end

            % Erstelle eine Tabelle mit separaten Spalten für Sensor, Knoten und Richtung
            t = table(keys', knoten, richtung);

            spaltenNamen =  {'Sensor', 'Knoten', 'Richtung'};
            app.SensorDataConnectionTable.ColumnName = spaltenNamen;
            % Setze die Data- und ColumnName-Eigenschaften der UITable
            app.SensorDataConnectionTable.Data = t;

            % Erstelle 2. Map Zuordnung von Knoten zu Messkanälen
            app.createNodeToSensorsMap();
            allNodes = app.nodeToSensorsMap.keys();                % Get all node numbers
            validNodes = [];                                  % Initialize array for nodes with data
            
            % Find which nodes have at least one sensor
            for k = 1:length(allNodes)
                nodeData = app.nodeToSensorsMap(allNodes{k});
                if ~isempty(nodeData.x) || ~isempty(nodeData.y) || ~isempty(nodeData.z)
                    validNodes(end+1) = allNodes{k};  % Add node to list if it has any sensor
                end
            end
            
            % Sort the nodes numerically
            validNodes = sort(validNodes);
            
            % Create ListBox items
            neueItems = cell(1, length(validNodes));
            for i = 1:length(validNodes)
                neueItems{i} = sprintf('Node %d', validNodes(i));  % Or "N%d" for more compact display
            end
            
            % Update ListBox
            app.SensorenListBox.Items = neueItems;
            app.SensorenListBox.ItemsData = validNodes;  % Store actual node numbers
            
            % Set default selection if not empty
            if ~isempty(app.SensorenListBox.Items)
                app.SensorenListBox.Value = validNodes(1);  % Select first node
                % Plot data for initially selected node
                app.plotNodeData3D(validNodes(1));
            end

        end

        % Button pushed function: DraufsichtXYEbeneButton
        function DraufsichtXYEbeneButtonPushed(app, event)
            % Speichern der Sicht
            app.perspective = [0,90];
            % Einstellen der Sicht
            view(app.MovementAxes, app.perspective(1), app.perspective(2));
        end

        % Button pushed function: SeitansichtYZEbeneButton
        function SeitansichtYZEbeneButtonPushed(app, event)
            % Speichern der Sicht
            app.perspective = [90,0];
            % Einstellen der Sicht
            view(app.MovementAxes, app.perspective(1), app.perspective(2));
        end

        % Button pushed function: SeitansichtXZEbeneButton
        function SeitansichtXZEbeneButtonPushed(app, event)
            % Speichern der Sicht
            app.perspective = [0,0];
            % Einstellen der Sicht
            view(app.MovementAxes, app.perspective(1), app.perspective(2));
        end

        % Button pushed function: DiagolalButton
        function DiagolalButtonPushed(app, event)
            % Speichern der Sicht
            app.perspective = [-45,45];
            % Einstellen der Sicht
            view(app.MovementAxes, app.perspective(1), app.perspective(2));
        end

        % Value changing function: SliderFDD
        function SliderFDDValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxesFDD.XLim = changingValue;
        end

        % Button pushed function: CalculateCPSDButton
        function CalculateCPSDButtonPushed(app, event)
            % Berechne Cross Power Spectral Density (PXY) Matrix.
            % Überlagerung des FqS der Sensoren 

            % Holen von Variablen
            Acc = app.dataSensors;

            % Löschen alter Daten
            app.singularValues = [];            % Vector der Amplituden (Eigenwerte)
            app.frequencies = [];               % Vector der Frequenzen 
            app.selectedFrequencies = [];       % Matrix Ausgewählte Frequenzen [N x 2]
            app.eFrequencies = [];              % verfeinerte Eigenfrequenzen
            app.modes = [];                     % Modenformen aus SVD
            app.selectedModes = [];             % Ermittelte Eigenvektoren
            app.eModes = [];                    % Verbesserte Moden
            app.daempfungsgrad = [];            % Dämpfungsgrad der Ausgewählten Frequenzen 

            for I=1:size(Acc,2)
                for J=1:size(Acc,2)
                    % psd eig pxy (cross power spectral density), F ist
                    % Matrix aus Frequenzen 
                    if strcmp(app.WelchsAerageSwitch.Value, 'Off')
                        wind = rectwin(app.NWindowEditField.Value);
                        [PXY(I,J,:),F(I,J,:)]=cpsd(Acc(:,I),Acc(:,J),wind,[],[],app.abtastRate);
                    else
                        [PXY(I,J,:),F(I,J,:)]=cpsd(Acc(:,I),Acc(:,J),[],[],[],app.abtastRate);
                    end
                end
            end
            % Übertspeichern der Matrix F in einen Vector
            app.frequencies(:,1)=F(1,1,:);

            disp('Frequencies Length after CPSD');
            disp(length(app.frequencies));

            % Colpute Modes of the PSD at each frequency
            for J=1:size(PXY,3)
                [ug, s, ~] = svds(PXY(:,:,J),1);  % [U,S,V] = svds(A,1) 
                app.modes(:,J) = real(ug(:,1)) / norm(real(ug(:,1)));   % Normalisierung der Moden (Euklidische Norm -> norm(v) = Länge v)
                app.singularValues(J)=s(1);
            end

            % Grafische Ausgabe der Ergebnisse
            app.plotPSD(app.UIAxesFDD);
            xLimits = app.UIAxesFDD.XLim;
            app.SliderFDD.Limits = xLimits;
            app.SliderFDD.Value = xLimits;

        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)


            app.selectionProcess = true;
            
            % Listener für Mausklicks aktivieren
            set(app.UIFigure, 'WindowButtonDownFcn', @(src, event) app.onMouseClick(src, event));

            F = app.frequencies;
            s1 = app.singularValues;
            
            if ~isempty(app.selectedFrequencies)
                % Reset Plot if necessary
                cla(app.UIAxesFDD);      % , 'reset'
                hold(app.UIAxesFDD, 'on');    
                plot(app.UIAxesFDD, F, s1);
            end

            % Löschen alter Auswahl
            app.selectedFrequencies = [];

            while app.selectionProcess == true
            % for i = 1:3   

                % Auslesen des Rectangles nach Mausklick
                rectangle = drawrectangle('Parent', app.UIAxesFDD);
                
                if ~isvalid(app) || app.selectionProcess == false
                    break;
                end

                r = rectangle.Position;     % Liefert Achs-Koordinaten des Rechtecks
                delete(rectangle);

                % Holen der Mausposition bei Klick
                app.mousePos = app.UIFigure.CurrentPoint;
                
                % Position der Achsen (UIAxes) innerhalb der Figur
                plotPosition = app.UIAxesFDD.Position;  % [left bottom width height]
                
                % Berechnung der Mausposition relativ zu den Achsen
                mousePosInAxes = app.mousePos - plotPosition(1:2); % Entfernen der Achsen-Offset
                
                % Die Mausposition in den Achsen muss in den Grenzen von [0, 0] bis [width, height] liegen
                if mousePosInAxes(1) >= 0 && mousePosInAxes(1) <= plotPosition(3) && ...
                   mousePosInAxes(2) >= 0 && mousePosInAxes(2) <= plotPosition(4)
                    disp('Maus ist innerhalb des Plotbereichs!');
                else
                    % disp('Maus ist außerhalb des Plotbereichs!');
                    % disp('Rechteck außerhalb der Achsen - Auswahl ignoriert.');
                    continue; % Überspringe den Rest der Schleife und warte auf eine neue Auswahl
                end


                % Berechnung des Maximalwertes
                [~,P1]=min(abs(F-r(1)));
                [~,P2]=min(abs(F-(r(1)+r(3))));
                [~,B]=max(s1(P1:P2));
                Max=B+P1-1;             % Position des Frequenz am ausgewählten Peak
                
                
                % Überprüfe, ob die selectedFrequencies Matrix nicht leer ist
                if ~isempty(app.selectedFrequencies)
                    % Überprüfe, ob die Frequenz bereits in der Liste enthalten ist
                    if ~ismember(F(Max), app.selectedFrequencies(:,2))  % Spalte 2 enthält die Frequenzen
                        % Wenn die Frequenz noch nicht vorhanden ist, füge sie hinzu
                        app.selectedFrequencies(end+1,:) = [Max, F(Max)];
                        % Darstellen im Plot
                        scatter(app.UIAxesFDD, F(Max),s1(Max),'MarkerEdgeColor','b','MarkerFaceColor','b');         % Mark this peak
                    else
                        % Optional: Falls du eine Nachricht anzeigen willst, wenn die Frequenz bereits vorhanden ist
                        disp(['Die Frequenz ', num2str(F(Max)), ' ist bereits in der Liste enthalten.']);
                        continue;
                    end
                else
                    % Falls die selectedFrequencies Matrix leer ist, kannst du sie initialisieren
                    app.selectedFrequencies = [Max, F(Max)];
                    % Darstellen im Plot
                    scatter(app.UIAxesFDD, F(Max),s1(Max),'MarkerEdgeColor','b','MarkerFaceColor','b');         % Mark this peak
                end

                % Warte bis Auswahl bestätigt oder Abgelehnt
                while app.getPeakSelection() == 0
                    pause(0.5);
                    if app.selectionProcess == false
                        break;
                    end
                end

                % Verarbeiten der Auswahl
                if app.getPeakSelection() == 1 % Select Point
                    % Press space to continue peak selection
                    scatter(app.UIAxesFDD, F(Max),s1(Max),'MarkerEdgeColor','g','MarkerFaceColor','g');      % Mark this peak as green
                elseif app.getPeakSelection() == 2 % Unselect Point
                    % Press any other key to ignore this peak
                    app.selectedFrequencies(end,:)=[];
                    scatter(app.UIAxesFDD, F(Max),s1(Max),'MarkerEdgeColor','r','MarkerFaceColor','r');      % Mark this peak as red
                end
                app.setPeakSelection(0);

            end
        end

        % Button pushed function: FinishSelectionButton
        function FinishSelectionButtonPushed(app, event)
            app.selectionProcess = false;
            
            % Deaktiviere den Listener
            set(app.UIFigure, 'WindowButtonDownFcn', []);

            % Sortieren der Ausgewählten Peaks
            [~,Sr]=sort(app.selectedFrequencies(:,2));
            app.selectedFrequencies=app.selectedFrequencies(Sr,:); 
            
            % holen der Werte (übersichtlicher)
            Fp = app.selectedFrequencies;

            % Löschen alter Auswahl
            app.selectedModes = []; 
            % Selektieren der auswählen Peaks
            for J=1:size(Fp,1)
                app.selectedModes(:,J) = app.modes(:, Fp(J,1));
            end
            % Darstellung der Ergebnisse in Tabellen
            % app.showResultsFDD;

            % Update Grafiken
            app.plotSelectedPoints(app.UIAxesFDD);

            % Update Tables and Listboxes
            app.showSelectedModesInTable(app.selectedModes, app.ModesTable, 1); % selModes, uiTable, option
            app.showSelectedModesInTable(app.selectedModes, app.AnimationTable, 1); % selModes, uiTable, option
            app.updateListboxFrequencies(app.selectedFrequencies , app.UrspruenglicheFrequenzenListBox);
            app.showSelectedFrequenciesInTable(app.selectedFrequencies, app.FrequenzenTable);


        end

        % Button pushed function: EnhancedButton
        function EnhancedButtonPushed(app, event)
            macLevel = app.MACEditField.Value;

            % Berechne die Frequenzgruppen nach MAC kriterium
            app.frequenzGruppen = berechneMac(macLevel,app.selectedFrequencies, app.modes);
            
            % Berechne die verbesserten Moden 
            app.eModes = berechneEnhancedModes(app.frequenzGruppen,app.singularValues, app.modes);

            % % Berechne die verbesserten Frequenzen und den Dämpfungsgrad new
            numGroups = length(app.frequenzGruppen);
            app.eFrequencies = zeros(numGroups, 1);
            app.daempfungsgrad = zeros(numGroups, 1);
            % Berechnen er Frequenzen 
             for i = 1:numGroups
                app.selectPSD(i);
                app.fftToSignal(app.psd_auswahl);
                dominantFrequency = app.calculateFrequencyOfSignal(app.signal_rec,app.t_rec);
                app.eFrequencies(i) = dominantFrequency;
                app.daempfungsgrad(i) = NaN; 
             end

            % Darstellen der Daten
            app.showEFrequenciesInTable(app.eFrequencies, app.FrequenzenTable_3);
            app.showSelectedModesInTable(app.eModes, app.VerbesserteModesTable_2, 2);
            app.plotPSD(app.UIAxesFrequenzenMitMAC);
            app.plotMACModes(app.UIAxesFrequenzenMitMAC);
            app.updateListboxFrequencies(app.eFrequencies, app.AuswahlderdarzustellendenFrequenzListBox);
            app.updateListboxFrequencies(app.eFrequencies, app.AngezeigteFrequenzListBox_2);
            app.updateListboxFrequencies(app.eFrequencies, app.VerbesserteFrequenzenListBox);

            app.erstelleTabDaempfung(app.UITableDaempfung);
            
            app.daempfungsgrad
            % Setze Grenzen Für Slider
            xLimits = app.UIAxesFrequenzenMitMAC.XLim;
            app.MACGroupsSlider.Limits = xLimits;
            app.MACGroupsSlider.Value = xLimits;

            % plotten des ersten Reconstruierten Signals
            app.plotReconstructionOfSignal(1);


        end

        % Value changed function: AuswahlderdarzustellendenFrequenzListBox
        function AuswahlderdarzustellendenFrequenzListBoxValueChanged(app, event)
            selectedItem = app.AuswahlderdarzustellendenFrequenzListBox.Value;
            app.AngezeigteFrequenzListBox_2.Value = selectedItem;
            index = find(strcmp(app.AuswahlderdarzustellendenFrequenzListBox.Items, selectedItem));  % Index des Elements
            app.plotReconstructionOfSignal(index);
        end

        % Value changed function: VerbesserteFrequenzenListBox
        function VerbesserteFrequenzenListBoxValueChanged(app, event)
            app.selectedAnimation = 2;
            app.StartAnimationButtonPushed;
        end

        % Selection changed function: DifferenzderModenButtonGroup
        function DifferenzderModenButtonGroupSelectionChanged(app, event)
            app.StartAnimationButtonPushed;
        end

        % Callback function
        function MACGroupsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxesFrequenzenMitMAC.XLim = changingValue;
        end

        % Value changing function: SignalSlider
        function SignalSliderValueChanging2(app, event)
            changingValue = event.Value;
            app.UIAxes_3.XLim = changingValue;            
        end

        % Value changing function: ModeGroupSlider
        function ModeGroupSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes_2.XLim = changingValue;           
        end

        % Value changing function: MACGroupsSlider
        function MACGroupsSliderValueChanging2(app, event)
            changingValue = event.Value;
            app.UIAxesFrequenzenMitMAC.XLim = changingValue;
        end

        % Value changed function: AngezeigteFrequenzListBox_2
        function AngezeigteFrequenzListBox_2ValueChanged(app, event)
            selectedItem = app.AngezeigteFrequenzListBox_2.Value;
            app.AuswahlderdarzustellendenFrequenzListBox.Value = selectedItem;
            
            app.AuswahlderdarzustellendenFrequenzListBoxValueChanged;
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            selectedItem = app.AuswahlderdarzustellendenFrequenzListBox.Value;
            index = find(strcmp(app.AuswahlderdarzustellendenFrequenzListBox.Items, selectedItem));
            app.plotRegressionDaempfung(index);
        end

        % Button pushed function: SpeichereDmpfungsgradButton
        function SpeichereDmpfungsgradButtonPushed(app, event)
            selectedItem = app.AuswahlderdarzustellendenFrequenzListBox.Value;
            index = find(strcmp(app.AuswahlderdarzustellendenFrequenzListBox.Items, selectedItem));
        
            % Tab 1 - Update string data
            data = app.UITableDaempfung.Data;
            data{index,1} = sprintf('%.5f', app.GemittelteDmpfungEditField.Value);
            data{index,2} = sprintf('%.5f', app.MitRegressionermittelteDmpfungEditField.Value);
            app.UITableDaempfung.Data = data;
            
            % Tab 2 - Update string data
            data2 = app.FrequenzenTable_3.Data;
            data2{index,3} = sprintf('%.5f', app.MitRegressionermittelteDmpfungEditField.Value);
            app.FrequenzenTable_3.Data = data2;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
             [filename,path] = uigetfile('*.txt');
            app.filePathSchwingungen = fullfile(path,filename);
            % disp(["the following path has been selected: " ,app.filePathSchwingungen]);
            figure(app.UIFigure);
            app.LoadEditField.Value = 'File selected';
            app.LoadLamp.Color = 'g';           
            
            % Lesen und Speichern der Daten
            [app.timeVector, app.dataSensors] = readSensorData(app.filePathSchwingungen);
            % disp(app.dataSensors)
            
            % Anzeigen der Daten in Tabelle
            spaltenNamen = arrayfun(@(i) sprintf('Sensor %d', i), 1:size(app.dataSensors,2), 'UniformOutput', false);
            
            % Setze die Data- und ColumnName-Eigenschaften der UITable
            app.DataSensorTable.Data = app.dataSensors;         % Füllt die UITable mit der Matrix dataSemsors
            app.DataSensorTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Sensor 1", "Sensor 2", usw.
            
            % Befüllen der InfoBox
            matrixSize = size(app.dataSensors);
            app.anzahlDatenpunkte = matrixSize(1); % Anzahl der Zeilen
            app.anzahlSensoren = matrixSize(2)/3; % Anzahl der Spalten
            app.dauerMessung = max(app.timeVector);
            app.abtastRate = (length(app.timeVector)-1)/(max(app.timeVector)-min(app.timeVector));
            % Formatierter Text mit sprintf
            app.InfoboxTextArea_2.Value = sprintf( ...
            "Anzahl der Sensoren:\t\t%d\n" + ...
            "Anzahl der Datenpunkte:\t%d\n" + ...
            "Dauer des Signals:\t\t\t%.2f\n" + ...
            "Abtastrate in Hz:\t\t\t%.2f", ...
            app.anzahlSensoren, app.anzahlDatenpunkte, app.dauerMessung, app.abtastRate);


            %Todo: Befüllen EditField
            app.NWindowEditField.Value = app.anzahlDatenpunkte;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Grafische Darstellung der Daten
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isequal(app.SensorDataConnectionLamp.Color, [0 1 0])
                selectedNode = app.SensorenListBox.Value;
                disp('Ausgewählter Knoten:');
                disp(selectedNode);
                
                % Plot data for the selected node
                try
                    app.plotNodeData3D(selectedNode);
                catch ME
                    errordlg(sprintf('Fehler beim Plotten von Knoten %d: %s', selectedNode, ME.message), 'Plot-Fehler');
                end
            end

        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
            [filename,path] = uigetfile('*.txt');
           app.filePathPositionSensoren = fullfile(path,filename);
           figure(app.UIFigure);
           app.PositionEditField.Value = 'File selected';
           app.PositionLamp.Color = 'g';  

           % Lesen und Speichern der Daten
           app.positionSensors = readmatrix(app.filePathPositionSensoren); % 'NumHeaderLines', 1
           
           % Anzeigen der Daten in Tabelle
           spaltenNamen = {'Sensor Nr.', 'x','y','z'};

           % Setze die Data- und ColumnName-Eigenschaften der UITable
           app.SensorPositionTable.Data = app.positionSensors;         % Füllt die UITable mit der Matrix selectedModes
           app.SensorPositionTable.ColumnName = spaltenNamen;       % Setzt die Spaltennamen auf "Eigenvektor 1", "Eigenvektor 2", usw.

           % Die erste Spalte wird als Ganzzahl formatiert, der Rest bleibt standardmäßig.
           app.SensorPositionTable.ColumnFormat = {'numeric', 'short', 'short', 'short'};

           % Zeige Grafik, wenn ausreichend Daten vorhanden

           if ~isempty(app.connectionSensors)
               axes(app.PositionAxes_2);
               visualiseSensors(app, app.positionSensors, app.connectionSensors, app.PositionAxes_2);
               visualiseSensors(app, app.positionSensors, app.connectionSensors, app.MovementAxes);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Bilder', 'LogoTU.jpg');
            app.Image2 = uiimage(app.InfoTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Bilder', 'LogoStatik.JPG');
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = 'Auswertung von Schwingungsmessungen mittels Frequency Domain Decomposition';
            app.Image3 = uiimage(app.InfoTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Bilder', 'Copyright.JPG');
            app.InfosZurNutzungPanel = uipanel(app.InfoTab);
            app.InfosZurNutzungPanel.Title = 'Infos Zur Nutzung';
            app.TextArea_5 = uitextarea(app.InfosZurNutzungPanel);
            app.TextArea_5.Value = {'Diese App dient der Durchführung einer output-only Modalanalyse basierend auf der Frequency Domain Decomposition (FDD) und der erweiterten eFDD. Auf Grundlage von Messdaten können Eigenfrequenzen, Eigenformen und Dämpfungsgrade struktureller Systeme identifiziert und visualisiert werden.'; ''; 'Der Aufbau der App ist modular und gliedert sich in drei Hauptbereiche:'; ''; '- Import: Hochladen und Überprüfen der benötigten Eingabedateien (Messdaten, Sensorpositionen, Verbindungen).'; ''; '- Berechnung: Durchführung der Modalanalyse (FDD/eFDD), Auswahl relevanter Frequenzbereiche, Anwendung von Fensterfunktionen, Bestimmung der modalen Parameter.'; ''; '- Auswertung: Visuelle Darstellung der Frequenzspektren, Dämpfungsverläufe und animierte Schwingungsmoden zur qualitativen Bewertung der Ergebnisse.'; ''; 'Eine ausführliche Beschreibung aller Funktionen und Hintergründe zur Berechnung können der Studienarbeit oder dem zugehörigen GitHub-Repository - 👉 https://github.com/Robert4J/StudienarbeitStatik_Modalanalyse.git - entnommen werden.'; ''; 'Dort sind auch Beispieldateien zur Verfügung gestellt, mit denen die App getestet werden kann.'};
            app.ImportTab_2 = uitab(app.TabGroup);
            app.ImportTab_2.Title = 'Import';
            app.LoadDataButton = uibutton(app.ImportTab_2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data';
            app.LoadEditField = uieditfield(app.ImportTab_2, 'text');
            app.LoadEditField.Value = 'No File Selected';
            app.LoadLamp = uilamp(app.ImportTab_2);
            app.PositionButton = uibutton(app.ImportTab_2, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Text = 'Load sensor position';
            app.PositionEditField = uieditfield(app.ImportTab_2, 'text');
            app.PositionEditField.Value = 'No File Selected';
            app.PositionLamp = uilamp(app.ImportTab_2);
            app.ConnectionButton = uibutton(app.ImportTab_2, 'push');
            app.ConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectionButtonPushed, true);
            app.ConnectionButton.Text = 'Load sensor connection';
            app.ConnectionEditField = uieditfield(app.ImportTab_2, 'text');
            app.ConnectionEditField.Value = 'No File Selected';
            app.ConnectionLamp = uilamp(app.ImportTab_2);
            app.Panel = uipanel(app.ImportTab_2);
            app.Panel.Title = 'Panel';
            app.PositionAxes_2 = uiaxes(app.Panel);
            title(app.PositionAxes_2, 'Position der Sensoren')
            xlabel(app.PositionAxes_2, 'X')
            ylabel(app.PositionAxes_2, 'Y')
            zlabel(app.PositionAxes_2, 'Z')
            app.SensorPositionTable = uitable(app.Panel);
            app.SensorPositionTable.ColumnName = '';
            app.SensorPositionTable.RowName = {};
            app.SensorConnectionTable = uitable(app.Panel);
            app.SensorConnectionTable.ColumnName = '';
            app.SensorConnectionTable.RowName = {};
            app.DataSensorsLabel = uilabel(app.Panel);
            app.DataSensorsLabel.Text = 'Data Sensors';
            app.SensorPositionLabel = uilabel(app.Panel);
            app.SensorPositionLabel.Text = 'Sensor Position';
            app.SensorConnectionLabel = uilabel(app.Panel);
            app.SensorConnectionLabel.Text = 'Sensor Connection';
            app.DataSensorTable = uitable(app.Panel);
            app.DataSensorTable.ColumnName = '';
            app.DataSensorTable.RowName = {};
            app.SensorDataConnectionTable = uitable(app.Panel);
            app.SensorDataConnectionTable.ColumnName = '';
            app.SensorDataConnectionTable.RowName = {};
            app.SensorDataConnectionLabel = uilabel(app.Panel);
            app.SensorDataConnectionLabel.Text = 'Sensor-Data Connection';
            app.InfoboxTextArea_2Label = uilabel(app.ImportTab_2);
            app.InfoboxTextArea_2Label.Text = 'Infobox';
            app.InfoboxTextArea_2 = uitextarea(app.ImportTab_2);
            app.InfoboxTextArea_2.Editable = 'off';
            app.SensorDataConnectionButton = uibutton(app.ImportTab_2, 'push');
            app.SensorDataConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @SensorDataConnectionButtonPushed, true);
            app.SensorDataConnectionButton.Text = 'Load Position to Data';
            app.SensorDataConnectionEditField = uieditfield(app.ImportTab_2, 'text');
            app.SensorDataConnectionEditField.Value = 'No File Selected';
            app.SensorDataConnectionLamp = uilamp(app.ImportTab_2);
            app.VisualisierungSignaleTab = uitab(app.TabGroup);
            app.VisualisierungSignaleTab.Title = 'Visualisierung Signale';
            app.AxesFrequenzspektrum = uiaxes(app.VisualisierungSignaleTab);
            title(app.AxesFrequenzspektrum, 'FFT der Signale')
            xlabel(app.AxesFrequenzspektrum, 'X')
            ylabel(app.AxesFrequenzspektrum, 'Y')
            zlabel(app.AxesFrequenzspektrum, 'Z')
            app.Signal_yAchse = uiaxes(app.VisualisierungSignaleTab);
            app.Signal_xAchse = uiaxes(app.VisualisierungSignaleTab);
            app.Signal_zAchse = uiaxes(app.VisualisierungSignaleTab);
            app.SensorenListBoxLabel = uilabel(app.VisualisierungSignaleTab);
            app.SensorenListBoxLabel.Text = 'Auswahl Sensor';
            app.SensorenListBox = uilistbox(app.VisualisierungSignaleTab);
            app.SensorenListBox.Items = {};
            app.SensorenListBox.ValueChangedFcn = createCallbackFcn(app, @SensorenListBoxValueChanged, true);
            app.SensorenListBox.Value = {};
            app.SliderFrequenzspektrum = uislider(app.VisualisierungSignaleTab, 'range');
            app.SliderFrequenzspektrum.ValueChangingFcn = createCallbackFcn(app, @SliderFrequenzspektrumValueChanging, true);
            app.SliderSignale = uislider(app.VisualisierungSignaleTab, 'range');
            app.SliderSignale.ValueChangingFcn = createCallbackFcn(app, @SliderSignaleValueChanging, true);
            app.PeakAuswahlTab = uitab(app.TabGroup);
            app.PeakAuswahlTab.Title = 'Peak Auswahl';
            app.UIAxesFDD = uiaxes(app.PeakAuswahlTab);
            title(app.UIAxesFDD, 'SVD der PSD')
            xlabel(app.UIAxesFDD, 'X')
            ylabel(app.UIAxesFDD, 'Y')
            zlabel(app.UIAxesFDD, 'Z')
            app.TextArea_2 = uitextarea(app.PeakAuswahlTab);
            app.TextArea_2.Value = {'- Zeichnen Sie Rechtecke um Peaks mit gedrückter linker Maustaste'; ''; '- Bestätigen oder verwerfen Sie die Auswahl über die Buttons Confirm (grün) oder Deselect (rot)'};
            app.TextArea = uitextarea(app.PeakAuswahlTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Betätigen Sie den Button Calculate CPSD, um das Frequenzspektrum zu berechnen.'; 'Verwenden Sie anschließend die Optionen zur Auswahl und Analyse der Peaks:'; ''; '- #N - Window: Gibt die Fensterbreite (Anzahl an Frequenzpunkten) '; ''; '- Welchs Average: Verwended ein Hamming-Window und verwendet die Defaulteinstellungen der CPSD-methode (siehe Docu).'; ''; '- Select Peaks: Aktiviert den Auswahlmodus für Peaks im Spektrum. Zeichnen Sie mit der Maus ein Rechteck um den gewünschten Bereich.'; ''; '- Finish Selection: Schließt die Auswahl ab und berechnet die zugehörigen Eigenfrequenzen und Eigenformen.'; ''; '- MAC: Schwellenwert (zwischen 0 und 1) für die Modenähnlichkeit bei der Anwendung der erweiterten FDD (eFDD).'; ''; '- Enhanced: Aktiviert zusätzlich die Analyse mit eFDD.'; ''; 'Die berechneten Ergebnisse der FDD erscheinen nach Abschluss der Auswahl in der Tabelle auf der rechten Seite. Die Ergebnisse der eFDD '; ' siehe EFDD Details und Dämpfungs Berechnung '};
            app.ConfirmButton = uibutton(app.PeakAuswahlTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.DeselectButton = uibutton(app.PeakAuswahlTab, 'push');
            app.DeselectButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectButtonPushed, true);
            app.DeselectButton.Text = 'Deselect';
            app.Panel_2 = uipanel(app.PeakAuswahlTab);
            app.ModesTable = uitable(app.Panel_2);
            app.ModesTable.ColumnName = '';
            app.ModesTable.RowName = {};
            app.BerechneteModenLabel = uilabel(app.Panel_2);
            app.BerechneteModenLabel.Text = 'Berechnete Moden';
            app.FrequenzenTable = uitable(app.Panel_2);
            app.FrequenzenTable.ColumnName = '';
            app.FrequenzenTable.RowName = {};
            app.FrequenzenHzLabel = uilabel(app.Panel_2);
            app.FrequenzenHzLabel.Text = 'Frequenzen [Hz]';
            app.SliderFDD = uislider(app.PeakAuswahlTab, 'range');
            app.SliderFDD.ValueChangingFcn = createCallbackFcn(app, @SliderFDDValueChanging, true);
            app.CalculateFDDPanel = uipanel(app.PeakAuswahlTab);
            app.CalculateFDDPanel.Title = 'Calculate FDD';
            app.CalculateCPSDButton = uibutton(app.CalculateFDDPanel, 'push');
            app.CalculateCPSDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCPSDButtonPushed, true);
            app.CalculateCPSDButton.Text = 'Calculate CPSD';
            app.SelectPeaksButton = uibutton(app.CalculateFDDPanel, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.FinishSelectionButton = uibutton(app.CalculateFDDPanel, 'push');
            app.FinishSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSelectionButtonPushed, true);
            app.FinishSelectionButton.Text = 'FinishSelection';
            app.EnhancedButton = uibutton(app.CalculateFDDPanel, 'push');
            app.EnhancedButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancedButtonPushed, true);
            app.EnhancedButton.Text = 'Enhanced';
            app.MACEditFieldLabel = uilabel(app.CalculateFDDPanel);
            app.MACEditFieldLabel.Text = 'MAC';
            app.MACEditField = uieditfield(app.CalculateFDDPanel, 'numeric');
            app.MACEditField.Value = 0.8;
            app.NWindowEditFieldLabel = uilabel(app.CalculateFDDPanel);
            app.NWindowEditFieldLabel.Text = '#N - Window';
            app.NWindowEditField = uieditfield(app.CalculateFDDPanel, 'numeric');
            app.WelchsAerageSwitchLabel = uilabel(app.CalculateFDDPanel);
            app.WelchsAerageSwitchLabel.Text = 'Welchs Aerage';
            app.WelchsAerageSwitch = uiswitch(app.CalculateFDDPanel, 'slider');
            app.EFDDDetailsTab = uitab(app.TabGroup);
            app.EFDDDetailsTab.Title = 'EFDD Details';
            app.FrequenzenTable_3 = uitable(app.EFDDDetailsTab);
            app.FrequenzenTable_3.ColumnName = '';
            app.FrequenzenTable_3.RowName = {};
            app.FrequenzenundDmpfungsgradejeSDOFSystemLabel = uilabel(app.EFDDDetailsTab);
            app.FrequenzenundDmpfungsgradejeSDOFSystemLabel.Text = {'Frequenzen und Dämpfungsgrade '; 'je SDOF-System'};
            app.AngezeigteFrequenzLabel = uilabel(app.EFDDDetailsTab);
            app.AngezeigteFrequenzLabel.Text = {'Auswahl der '; 'darzustellen-'; 'den Frequenz'};
            app.AuswahlderdarzustellendenFrequenzListBox = uilistbox(app.EFDDDetailsTab);
            app.AuswahlderdarzustellendenFrequenzListBox.Items = {''};
            app.AuswahlderdarzustellendenFrequenzListBox.ValueChangedFcn = createCallbackFcn(app, @AuswahlderdarzustellendenFrequenzListBoxValueChanged, true);
            app.AuswahlderdarzustellendenFrequenzListBox.Value = '';
            app.TabGroup3 = uitabgroup(app.EFDDDetailsTab);
            app.VerbesserteModenundrekonstruiertesSignalTab = uitab(app.TabGroup3);
            app.VerbesserteModenundrekonstruiertesSignalTab.Title = 'Verbesserte Moden und rekonstruiertes Signal';
            app.UIAxes_3 = uiaxes(app.VerbesserteModenundrekonstruiertesSignalTab);
            title(app.UIAxes_3, 'Rücktransformiertes Signal')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.VerbesserteModenundrekonstruiertesSignalTab);
            title(app.UIAxes_2, 'Frequenzspektrum Auswahl')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxesFrequenzenMitMAC = uiaxes(app.VerbesserteModenundrekonstruiertesSignalTab);
            title(app.UIAxesFrequenzenMitMAC, 'SVD der PSD mit Kennzeichnung der SDOF-Systeme')
            xlabel(app.UIAxesFrequenzenMitMAC, 'X')
            ylabel(app.UIAxesFrequenzenMitMAC, 'Y')
            zlabel(app.UIAxesFrequenzenMitMAC, 'Z')
            app.MACGroupsSlider = uislider(app.VerbesserteModenundrekonstruiertesSignalTab, 'range');
            app.MACGroupsSlider.ValueChangingFcn = createCallbackFcn(app, @MACGroupsSliderValueChanging2, true);
            app.ModeGroupSlider = uislider(app.VerbesserteModenundrekonstruiertesSignalTab, 'range');
            app.ModeGroupSlider.ValueChangingFcn = createCallbackFcn(app, @ModeGroupSliderValueChanging, true);
            app.SignalSlider = uislider(app.VerbesserteModenundrekonstruiertesSignalTab, 'range');
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @SignalSliderValueChanging2, true);
            app.DmpfungsBerechnungTab = uitab(app.TabGroup);
            app.DmpfungsBerechnungTab.Title = 'Dämpfungs Berechnung';
            app.UIAxesSignalMitPeaks = uiaxes(app.DmpfungsBerechnungTab);
            title(app.UIAxesSignalMitPeaks, 'Title')
            xlabel(app.UIAxesSignalMitPeaks, 'X')
            ylabel(app.UIAxesSignalMitPeaks, 'Y')
            zlabel(app.UIAxesSignalMitPeaks, 'Z')
            app.UIAxesLogPeaks = uiaxes(app.DmpfungsBerechnungTab);
            title(app.UIAxesLogPeaks, 'Title')
            xlabel(app.UIAxesLogPeaks, 'X')
            ylabel(app.UIAxesLogPeaks, 'Y')
            zlabel(app.UIAxesLogPeaks, 'Z')
            app.UIAxesHistogramDurchschnitt = uiaxes(app.DmpfungsBerechnungTab);
            title(app.UIAxesHistogramDurchschnitt, 'Title')
            xlabel(app.UIAxesHistogramDurchschnitt, 'X')
            ylabel(app.UIAxesHistogramDurchschnitt, 'Y')
            zlabel(app.UIAxesHistogramDurchschnitt, 'Z')
            app.Slider = uislider(app.DmpfungsBerechnungTab, 'range');
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Panel_3 = uipanel(app.DmpfungsBerechnungTab);
            app.UITableDaempfung = uitable(app.Panel_3);
            app.UITableDaempfung.ColumnName = {'Gespeicherte Dämpfung'};
            app.UITableDaempfung.RowName = {'regression; durchschnitt'};
            app.MitRegressionermittelteDmpfungEditFieldLabel = uilabel(app.Panel_3);
            app.MitRegressionermittelteDmpfungEditFieldLabel.Text = {'Mit Regression er-'; 'mittelte Dämpfung'};
            app.MitRegressionermittelteDmpfungEditField = uieditfield(app.Panel_3, 'numeric');
            app.MitRegressionermittelteDmpfungEditField.Editable = 'off';
            app.GemittelteDmpfungEditFieldLabel = uilabel(app.Panel_3);
            app.GemittelteDmpfungEditFieldLabel.Text = 'Gemittelte Dämpfung';
            app.GemittelteDmpfungEditField = uieditfield(app.Panel_3, 'numeric');
            app.GemittelteDmpfungEditField.Editable = 'off';
            app.SpeichereDmpfungsgradButton = uibutton(app.Panel_3, 'push');
            app.SpeichereDmpfungsgradButton.ButtonPushedFcn = createCallbackFcn(app, @SpeichereDmpfungsgradButtonPushed, true);
            app.SpeichereDmpfungsgradButton.Text = 'Speichere Dämpfungsgrad';
            app.AngezeigteFrequenzListBox_2Label = uilabel(app.Panel_3);
            app.AngezeigteFrequenzListBox_2Label.Text = {'Angezeigte '; 'Frequenz '};
            app.AngezeigteFrequenzListBox_2 = uilistbox(app.Panel_3);
            app.AngezeigteFrequenzListBox_2.Items = {''};
            app.AngezeigteFrequenzListBox_2.ValueChangedFcn = createCallbackFcn(app, @AngezeigteFrequenzListBox_2ValueChanged, true);
            app.AngezeigteFrequenzListBox_2.Value = '';
            app.EigenformenTab = uitab(app.TabGroup);
            app.EigenformenTab.Title = 'Eigenformen';
            app.MovementAxes = uiaxes(app.EigenformenTab);
            xlabel(app.MovementAxes, 'X')
            ylabel(app.MovementAxes, 'Y')
            zlabel(app.MovementAxes, 'Z')
            app.AnimationTable = uitable(app.EigenformenTab);
            app.AnimationTable.ColumnName = '';
            app.AnimationTable.RowName = {};
            app.ControlleAnimationPanel = uipanel(app.EigenformenTab);
            app.ControlleAnimationPanel.Title = 'Controlle Animation';
            app.StartAnimationButton = uibutton(app.ControlleAnimationPanel, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.PauseButton = uibutton(app.ControlleAnimationPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.WhleeineFrequenzLabel = uilabel(app.ControlleAnimationPanel);
            app.WhleeineFrequenzLabel.Text = {'Ursprüngliche'; 'Frequenzen'};
            app.UrspruenglicheFrequenzenListBox = uilistbox(app.ControlleAnimationPanel);
            app.UrspruenglicheFrequenzenListBox.Items = {};
            app.UrspruenglicheFrequenzenListBox.ValueChangedFcn = createCallbackFcn(app, @UrspruenglicheFrequenzenListBoxValueChanged, true);
            app.UrspruenglicheFrequenzenListBox.Value = {};
            app.SkallierenderVerschiebungEditFieldLabel = uilabel(app.ControlleAnimationPanel);
            app.SkallierenderVerschiebungEditFieldLabel.Text = {'Skallieren der '; 'Verschiebung'};
            app.SkallierenderVerschiebungEditField = uieditfield(app.ControlleAnimationPanel, 'numeric');
            app.SkallierenderVerschiebungEditField.ValueChangedFcn = createCallbackFcn(app, @SkallierenderVerschiebungEditFieldValueChanged, true);
            app.SkallierenderVerschiebungEditField.Value = 1;
            app.WhleeineFrequenzLabel_2 = uilabel(app.ControlleAnimationPanel);
            app.WhleeineFrequenzLabel_2.Text = {'Verbesserte '; 'Freqeunzen'};
            app.VerbesserteFrequenzenListBox = uilistbox(app.ControlleAnimationPanel);
            app.VerbesserteFrequenzenListBox.Items = {};
            app.VerbesserteFrequenzenListBox.ValueChangedFcn = createCallbackFcn(app, @VerbesserteFrequenzenListBoxValueChanged, true);
            app.VerbesserteFrequenzenListBox.Value = {};
            app.TextArea_4 = uitextarea(app.ControlleAnimationPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Wähle Sie die Frequenz, die angezeigt werden soll. Die angezeigte Frequenz ist grün marktiert. Bei ausgabe der Differenz der Moden leuchten bleide Lampen blau.'};
            app.DifferenzderModenButtonGroup = uibuttongroup(app.ControlleAnimationPanel);
            app.DifferenzderModenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifferenzderModenButtonGroupSelectionChanged, true);
            app.DifferenzderModenButtonGroup.Title = 'Differenz der Moden';
            app.aktivButton = uiradiobutton(app.DifferenzderModenButtonGroup);
            app.aktivButton.Text = 'aktiv';
            app.inaktivButton = uiradiobutton(app.DifferenzderModenButtonGroup);
            app.inaktivButton.Text = 'inaktiv';
            app.inaktivButton.Value = true;
            app.UrspruenglichLamp = uilamp(app.ControlleAnimationPanel);
            app.VerbessertLamp = uilamp(app.ControlleAnimationPanel);
            app.DraufsichtXYEbeneButton = uibutton(app.EigenformenTab, 'push');
            app.DraufsichtXYEbeneButton.ButtonPushedFcn = createCallbackFcn(app, @DraufsichtXYEbeneButtonPushed, true);
            app.DraufsichtXYEbeneButton.Text = {'Draufsicht'; 'XY-Ebene'};
            app.SeitansichtYZEbeneButton = uibutton(app.EigenformenTab, 'push');
            app.SeitansichtYZEbeneButton.ButtonPushedFcn = createCallbackFcn(app, @SeitansichtYZEbeneButtonPushed, true);
            app.SeitansichtYZEbeneButton.Text = {'Seitansicht'; 'YZ-Ebene'};
            app.SeitansichtXZEbeneButton = uibutton(app.EigenformenTab, 'push');
            app.SeitansichtXZEbeneButton.ButtonPushedFcn = createCallbackFcn(app, @SeitansichtXZEbeneButtonPushed, true);
            app.SeitansichtXZEbeneButton.Text = {'Seitansicht'; 'XZ-Ebene'};
            app.DiagolalButton = uibutton(app.EigenformenTab, 'push');
            app.DiagolalButton.ButtonPushedFcn = createCallbackFcn(app, @DiagolalButtonPushed, true);
            app.DiagolalButton.Text = 'Diagolal';
            app.AnnimationderVerschiebungLabel = uilabel(app.EigenformenTab);
            app.AnnimationderVerschiebungLabel.Text = 'Annimation der Verschiebung';
            app.VerbesserteModesTable_2 = uitable(app.EigenformenTab);
            app.VerbesserteModesTable_2.ColumnName = '';
            app.VerbesserteModesTable_2.RowName = {};
            app.ModenFDDLabel = uilabel(app.EigenformenTab);
            app.ModenFDDLabel.Text = 'Moden FDD';
            app.ModeneFDDLabel = uilabel(app.EigenformenTab);
            app.ModeneFDDLabel.Text = 'Moden eFDD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modalanalyse_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end